classdef Pedal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MixKnobLabel      matlab.ui.control.Label
        MixKnob           matlab.ui.control.Knob
        WetnessKnobLabel  matlab.ui.control.Label
        WetnessKnob       matlab.ui.control.Knob
        DecayKnobLabel    matlab.ui.control.Label
        DecayKnob         matlab.ui.control.Knob
        Switch            matlab.ui.control.ToggleSwitch
    end

    
    properties (Access = public)
        
    end
    

    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value
            if value == 'On'
            fileReader = dsp.AudioFileReader('gitarr.wav','SamplesPerFrame',1024);
            deviceWriter = audioDeviceWriter('SampleRate', fileReader.SampleRate);
            
            while ~isDone(fileReader)
                audio = fileReader();
                deviceWriter(audio);
            end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.MixKnobLabel = uilabel(app.UIFigure);
            app.MixKnobLabel.Text = 'Mix';
            app.MixKnob = uiknob(app.UIFigure, 'continuous');
            app.WetnessKnobLabel = uilabel(app.UIFigure);
            app.WetnessKnobLabel.Text = 'Wetness';
            app.WetnessKnob = uiknob(app.UIFigure, 'continuous');
            app.DecayKnobLabel = uilabel(app.UIFigure);
            app.DecayKnobLabel.Text = 'Decay';
            app.DecayKnob = uiknob(app.UIFigure, 'continuous');
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Pedal

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end